classdef DELLO_Review_Pannel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DELLO_ReviewUIFigure            matlab.ui.Figure
        UIAxes_1                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        ImportNameFileButton            matlab.ui.control.Button
        ImportIndividualPositionButton  matlab.ui.control.Button
        ImportMNIPositionButton         matlab.ui.control.Button
        ExportSlicesButton              matlab.ui.control.Button
        ExportElectrodeInfoButton       matlab.ui.control.Button
        UITableElelctrode               matlab.ui.control.Table
        ImportImageFileButton           matlab.ui.control.Button
        AAL3EditField                   matlab.ui.control.EditField
        YEO7EditField                   matlab.ui.control.EditField
        GWEditField                     matlab.ui.control.EditField
        GreyWhiteMatterLabel            matlab.ui.control.Label
        AAL3AtlasLabel                  matlab.ui.control.Label
        Yeo7AtlasLabel                  matlab.ui.control.Label
        EditFieldStatus1                matlab.ui.control.EditField
        EditFieldStatus2                matlab.ui.control.EditField
        EditFieldStatus3                matlab.ui.control.EditField
        EditFieldStatus4                matlab.ui.control.EditField
    end

    
    properties (Access = private)
        ElectrodeNames
        ElectrodePos
        ElectrodeMNIPos
        
        IndividualMRI
        
        AAL3Matrix
        AAL3Labels
        
        Yeo7Matrix
        Yeo7Labels
        
        GreyMatrix
        AnatMat
        CTmat
        
        DepthElectrodeObj
        
        CorrTempRaw
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DepthObj)
            app.DepthElectrodeObj = DepthObj;
            % app.AnatMat = niftiread(app.DepthElectrodeObj.AnatFile);
            
            % Anat
            infoMRI   = niftiinfo('r3DT1.nii');
            app.AnatMat = niftiread(infoMRI);
            % CT with brain mask
            infoCT   = niftiinfo('postCT.nii');
            app.CTmat = niftiread(infoCT);
            % Brain mask threshold
            BrainMaskF  = niftiinfo('BrainMask.nii');
            BrainMask   = niftiread(BrainMaskF);
            app.CTmat(~BrainMask) = 0;
            
            % Initialize the axis
            camroll(app.UIAxes_1,90)
            camroll(app.UIAxes_2,90)
            camroll(app.UIAxes_3,90)
            
            app.UIAxes_1.XTickLabel = [];
            app.UIAxes_2.XTickLabel = [];
            app.UIAxes_3.XTickLabel = [];
            
            app.UIAxes_1.YTickLabel = [];
            app.UIAxes_2.YTickLabel = [];
            app.UIAxes_3.YTickLabel = [];
            
            app.UIAxes_1.XColor = [1 1 1];
            app.UIAxes_2.XColor = [1 1 1];
            app.UIAxes_3.XColor = [1 1 1];
            
            app.UIAxes_1.YColor = [1 1 1];
            app.UIAxes_2.YColor = [1 1 1];
            app.UIAxes_3.YColor = [1 1 1];
        end

        % Button pushed function: ImportNameFileButton
        function ImportNameFileButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            ElectrodeNameFile = [path,file];
            app.ElectrodeNames = importdata(ElectrodeNameFile);
            app.UITableElelctrode.Data = app.ElectrodeNames;
            app.EditFieldStatus1.Value = 'OK';
            app.DELLO_ReviewUIFigure.Visible = 'Off';
            app.DELLO_ReviewUIFigure.Visible = 'On';
        end

        % Cell selection callback: UITableElelctrode
        function UITableElelctrodeCellSelection(app, event)
            value = event.Indices(1);
            IndividualCorr = app.DepthElectrodeObj.ElectrodePos(value,:);
            % Convert to slice ijk
            % In MNI space
            infoFST1 = niftiinfo('r3DT1.nii');
            M_FS = infoFST1.Transform.T';
            
            % Read contacts raw coordinates
            ContactsPos = IndividualCorr;
            ContactsPos(:,4) = 1;
            
            
            ContactsPostionsNewijk = zeros(size(ContactsPos,1),4);
            
            for i = 1:size(ContactsPos,1)
                ContactsPostionsNewijk(i,:) = inv(M_FS)*ContactsPos(i,:)';
            end
            
            ContactsPostionsNewijk = ContactsPostionsNewijk(:,1:3);
            ContactsPostionsNewijk = round(ContactsPostionsNewijk);
            
            app.CorrTempRaw = ContactsPostionsNewijk;
            CorrTemp  = [app.CorrTempRaw(:,1),app.CorrTempRaw(:,2),app.CorrTempRaw(:,3)];
            
            % Axis 1
            Atemp = squeeze(app.AnatMat(round(CorrTemp(1)),:,:));
            Btemp = squeeze(app.CTmat(round(CorrTemp(1)),:,:));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_1,Ctemp);
            hold(app.UIAxes_1,'on')
            plot(app.UIAxes_1,CorrTemp(3),CorrTemp(2),'r*')
            colormap(app.UIAxes_1,'gray')
            axis(app.UIAxes_1,'tight')
            
            % Axis 2
            Atemp = squeeze(app.AnatMat(:,round(CorrTemp(2)),:));
            Btemp = squeeze(app.CTmat(:,round(CorrTemp(2)),:));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_2,Ctemp);
            hold(app.UIAxes_2,'on')
            plot(app.UIAxes_2,CorrTemp(3),CorrTemp(1),'r*')
            colormap(app.UIAxes_2,'gray')
            axis(app.UIAxes_2,'tight')
            
            % Axis 3
            Atemp = squeeze(app.AnatMat(:,:,round(CorrTemp(3))));
            Btemp = squeeze(app.CTmat(:,:,round(CorrTemp(3))));
            Ctemp = imfuse(Atemp,Btemp,'blend');
            im = imagesc(app.UIAxes_3,Ctemp);
            hold(app.UIAxes_3,'on')
            plot(app.UIAxes_3,CorrTemp(2),CorrTemp(1),'r*')
            colormap(app.UIAxes_3,'gray')
            axis(app.UIAxes_3,'tight')
            if app.DepthElectrodeObj.WhiteMatterLoc(value) == 0
                app.GWEditField.Value = 'Grey matter';
            elseif app.DepthElectrodeObj.WhiteMatterLoc(value) == 1
                app.GWEditField.Value = 'White matter';
            end
            
            if app.DepthElectrodeObj.AAL3Index(value) == 0
                app.AAL3EditField.Value = 'Not Available';
            else
                app.AAL3EditField.Value = app.DepthElectrodeObj.AAL3Labels.textdata{app.DepthElectrodeObj.AAL3Index(value),2};
            end
            
            
            if app.DepthElectrodeObj.Yeo7Index(value) == 0
                app.YEO7EditField.Value = 'Not available';
            else
                app.YEO7EditField.Value = app.DepthElectrodeObj.Yeo7Labels{app.DepthElectrodeObj.Yeo7Index(value)};
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DELLO_ReviewUIFigure = uifigure;
            app.DELLO_ReviewUIFigure.Name = 'DELLO_Review';
            app.UIAxes_1 = uiaxes(app.DELLO_ReviewUIFigure);
            title(app.UIAxes_1, 'SliceView1')
            app.UIAxes_2 = uiaxes(app.DELLO_ReviewUIFigure);
            title(app.UIAxes_2, 'SliceView2')
            app.UIAxes_3 = uiaxes(app.DELLO_ReviewUIFigure);
            title(app.UIAxes_3, 'SliceView3')
            app.ImportNameFileButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ImportNameFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportNameFileButtonPushed, true);
            app.ImportNameFileButton.Text = 'Import Name File';
            app.ImportIndividualPositionButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ImportIndividualPositionButton.Text = 'Import Individual Position';
            app.ImportMNIPositionButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ImportMNIPositionButton.Text = 'Import MNI Position';
            app.ExportSlicesButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ExportSlicesButton.Text = 'Export Slices';
            app.ExportElectrodeInfoButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ExportElectrodeInfoButton.Text = 'Export Electrode Info';
            app.UITableElelctrode = uitable(app.DELLO_ReviewUIFigure);
            app.UITableElelctrode.ColumnName = {'Electrode'};
            app.UITableElelctrode.RowName = {};
            app.UITableElelctrode.CellSelectionCallback = createCallbackFcn(app, @UITableElelctrodeCellSelection, true);
            app.ImportImageFileButton = uibutton(app.DELLO_ReviewUIFigure, 'push');
            app.ImportImageFileButton.Text = 'Import Image File';
            app.AAL3EditField = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.YEO7EditField = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.GWEditField = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.GreyWhiteMatterLabel = uilabel(app.DELLO_ReviewUIFigure);
            app.GreyWhiteMatterLabel.Text = 'Grey & White Matter';
            app.AAL3AtlasLabel = uilabel(app.DELLO_ReviewUIFigure);
            app.AAL3AtlasLabel.Text = 'AAL3 Atlas';
            app.Yeo7AtlasLabel = uilabel(app.DELLO_ReviewUIFigure);
            app.Yeo7AtlasLabel.Text = 'Yeo 7 Atlas';
            app.EditFieldStatus1 = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.EditFieldStatus1.Value = 'Pending';
            app.EditFieldStatus2 = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.EditFieldStatus2.Value = 'Pending';
            app.EditFieldStatus3 = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.EditFieldStatus3.Value = 'Pending';
            app.EditFieldStatus4 = uieditfield(app.DELLO_ReviewUIFigure, 'text');
            app.EditFieldStatus4.Value = 'Pending';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DELLO_Review_Pannel(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DELLO_ReviewUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DELLO_ReviewUIFigure)
        end
    end
end